classdef multiTformGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RemoveImageButton          matlab.ui.control.Button
        AddImageButton             matlab.ui.control.Button
        ImagesLabel                matlab.ui.control.Label
        ImagesTable                matlab.ui.control.Table
        TformTable                 matlab.ui.control.Table
        ImageTypeDropDown          matlab.ui.control.DropDown
        OutputFormatDropDownLabel  matlab.ui.control.Label
        OutputDirLabel             matlab.ui.control.Label
        OutputDirButton            matlab.ui.control.Button
        STARTButton                matlab.ui.control.Button
        ClearAllButton             matlab.ui.control.Button
        HelpButton                 matlab.ui.control.Button
        MoveDownButton             matlab.ui.control.Button
        MoveUpButton               matlab.ui.control.Button
        RemoveTformButton          matlab.ui.control.Button
        AddTformButton             matlab.ui.control.Button
        TransformsLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        tp = struct('chain',{},'fname',{},'im',{},'jac',{});
        tsel = []; % Current transform table selection
        isel = []; % Current image table selection
        odir = ''; % Output directory
        fmt = '.nii.gz'; % Output image format
    end
    
    methods (Access = private)
        
        function C = fnameLimit(~,C,N)
            if ~isempty(C)
                C(:,2) = cellfun(@(x)['... ',x(max(1,length(x)-N):end)],C(:,2),'UniformOutput',false);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            str = {'TRANSFORMS:';...
                   ['Add TransformParameters.*.txt files in forward order ',...
                    '(transforms are applied backwards, from end of list).',...
                    'Mark final transformations in the chain in the first column. ',...
                    'Images at the end will be transformed up through the chain until ',...
                    'a final transform is reached.'];...
                   '';...
                   'IMAGES:';...
                   'MHD images may be added to any step in the chain to be transformed from that point upward.';...
                   '';...
                   'OUTPUT:';...
                   ['All transforms, images, and a .log file are saved in the same selected directory. , '...
                    'Images have the following format: [fname].[chain#].[step#].[image#].mhd ',...
                    'and original file names are saved in the log.']};
            msgbox(str,'MultiTransform HELP','help','modal');
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.tp = struct('chain',{},'fname',{},'im',{},'jac',{});
            app.tsel = [];
            app.isel = [];
            app.TformTable.Data = {};
            app.ImagesTable = {};
        end

        % Button pushed function: AddTformButton
        function AddTformButtonPushed(app, event)
            [fname,fdir] = uigetfile('*.txt','TransformParameters');
            if ischar(fname)
                i = length(app.tp) + 1;
                app.tp(i).fname = fullfile(fdir,fname);
                app.tp(i).im = {};
                app.tp(i).jac = false;
                if i==1
                    app.tp(i).chain = 1;
                    tval = true;
                else
                    app.tp(i).chain = app.tp(i-1).chain;
                    tval = false;
                end
                
                % Update table
                C = app.fnameLimit({tval,app.tp(i).fname,app.tp(i).jac},100);
                if isempty(app.TformTable.Data)
                    app.TformTable.Data = C;
                else
                    app.TformTable.Data(end+1,:) = C;
                end
                
                % Find default value in file:
                p = readTransformParam(fullfile(fdir,fname));
                if isfield(p,'DefaultPixelValue')
                    defVal = p.DefaultPixelValue;
                else
                    defVal = 0;
                end
                app.tp(i).defOrig = defVal;
            end
        end

        % Button pushed function: RemoveTformButton
        function RemoveTformButtonPushed(app, event)
            i = app.tsel;
            if i
                app.tp(i) = [];
                app.TformTable.Data(i,:) = [];
                if isempty(app.tp)
                    i = [];
                else
                    i = max(length(app.tp),i);
                end
                app.TformTableCellSelection(app,struct('Indices',i))
            end

        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            i = app.tsel;
            if i
                app.tp([i-1,i]) = app.tp([i,i-1]);
                app.TformTable.Data([i-1,i],:) = app.TformTable.Data([i,i-1],:);
                app.tsel = i-1;
            end

        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            i = app.tsel;
            if i
                app.tp([i,i+1]) = app.tp([i+1,i]);
                app.TformTable.Data([i,i+1],:) = app.TformTable.Data([i+1,i],:);
                app.tsel = i+1;
            end
        end

        % Cell edit callback: TformTable
        function TformTableCellEdit(app, event)
            ind = event.Indices;
            if ind(2) == 1 % Transform chain final
                if ind(1) == 1
                    % First transform MUST be a final
                    app.TformTable.Data{1} = true;
                else
                    chain = cumsum([app.TformTable.Data{:,1}]);
                    for i = 1:length(app.tp)
                        app.tp(i).chain = chain(i);
                    end
                end
            elseif ind(2) == 3 % Jac
                app.tp(ind(1)).jac = event.NewData;
            end
        end

        % Cell selection callback: TformTable
        function TformTableCellSelection(app, event)
            i = event.Indices;
            if ~isempty(i)
                i = i(1);
                app.tsel = i;
                app.ImagesTable.Data = app.fnameLimit(app.tp(i).im,80);
            else
                app.ImagesTable.Data = {};
            end
        end

        % Button pushed function: AddImageButton
        function AddImageButtonPushed(app, event)
            i = app.tsel;
            if i % make sure there is a Transform loaded and selected
                filt = {'*.nii.gz';'*.nii';'*.mhd'};
                [fname,fdir] = uigetfile(filt,'Images','MultiSelect','on');
                if fdir
                    if ischar(fname)
                        fname = {fname};
                    end
                    nf = length(fname);
                    % Determine if interp should be nearest neighbor:
                    nn = contains(fname,{'VOI','ADC'});
                    % Set output file names:
                    oname = strcat(strtok(fname,'.'),'_mR');
                    defVal = num2cell(app.tp(i).defOrig*ones(nf,1));
                    im = [app.tp(i).im ; num2cell(nn)',fullfile(fdir,fname)',oname',defVal];
                    app.tp(i).im = im;
                    app.ImagesTable.Data = app.fnameLimit(im,80);
                end
            end
        end

        % Button pushed function: RemoveImageButton
        function RemoveImageButtonPushed(app, event)
            i = app.tsel;
            j = app.isel;
            if i
                app.tp(i).im(j,:) = [];
                app.ImagesTable.Data = app.fnameLimit(app.tp(i).im,80);
            end
        end

        % Cell edit callback: ImagesTable
        function ImagesTableCellEdit(app, event)
            ind = event.Indices;
            newData = event.NewData;
            i = app.tsel;
            switch ind(2)
                case 1 % NN check
                    app.tp(i).im{ind(1),1} = newData;
                case 3 % Output name
                    app.tp(i).im{ind(1),3} = validateFilename(newData);
                case 4 % Default Value
                    newData = str2double(newData);
                    if isnan(newData)
                        app.ImagesTable.Data{ind(1),4} = num2str(app.tp(i).im{ind(1),4});
                    else
                        app.tp(i).im{ind(1),4} = newData;
                    end
            end
        end

        % Cell selection callback: ImagesTable
        function ImagesTableCellSelection(app, event)
            i = event.Indices;
            if ~isempty(i)
                i = i(1);
            end
            app.isel = i;
        end

        % Button pushed function: OutputDirButton
        function OutputDirButtonPushed(app, event)
            tdir = app.odir;
            if isempty(tdir)
                tdir = pwd;
            else
                tdir = fileparts(tdir);
            end
            tdir = uigetdir(tdir,'Output directory');
            if tdir~=0
                app.odir = tdir;
                app.OutputDirLabel.Text = tdir;
            end
        end

        % Value changed function: ImageTypeDropDown
        function ImageTypeDropDownValueChanged(app, event)
            app.fmt = app.ImageTypeDropDown.Value;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            multiTform(app.tp,app.odir,app.fmt)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransformsLabel = uilabel(app.UIFigure);
            app.TransformsLabel.Text = 'Transforms:';
            app.AddTformButton = uibutton(app.UIFigure, 'push');
            app.AddTformButton.ButtonPushedFcn = createCallbackFcn(app, @AddTformButtonPushed, true);
            app.AddTformButton.Text = 'Add';
            app.RemoveTformButton = uibutton(app.UIFigure, 'push');
            app.RemoveTformButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveTformButtonPushed, true);
            app.RemoveTformButton.Text = 'Remove';
            app.MoveUpButton = uibutton(app.UIFigure, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.UIFigure, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.OutputDirButton = uibutton(app.UIFigure, 'push');
            app.OutputDirButton.ButtonPushedFcn = createCallbackFcn(app, @OutputDirButtonPushed, true);
            app.OutputDirButton.Text = 'Output Dir:';
            app.OutputDirLabel = uilabel(app.UIFigure);
            app.OutputFormatDropDownLabel = uilabel(app.UIFigure);
            app.OutputFormatDropDownLabel.Text = 'Output Format:';
            app.ImageTypeDropDown = uidropdown(app.UIFigure);
            app.ImageTypeDropDown.Items = {'.nii.gz', '.nii', '.mhd'};
            app.ImageTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageTypeDropDownValueChanged, true);
            app.ImageTypeDropDown.Value = '.nii.gz';
            app.TformTable = uitable(app.UIFigure);
            app.TformTable.ColumnName = {'Final'; 'Filename'; 'Jac'};
            app.TformTable.RowName = {};
            app.TformTable.ColumnEditable = [true false true];
            app.TformTable.CellEditCallback = createCallbackFcn(app, @TformTableCellEdit, true);
            app.TformTable.CellSelectionCallback = createCallbackFcn(app, @TformTableCellSelection, true);
            app.ImagesTable = uitable(app.UIFigure);
            app.ImagesTable.ColumnName = {'NN'; 'ImageName'; 'OutName'; 'DefaultValue'};
            app.ImagesTable.RowName = {};
            app.ImagesTable.ColumnEditable = [true false true true];
            app.ImagesTable.CellEditCallback = createCallbackFcn(app, @ImagesTableCellEdit, true);
            app.ImagesTable.CellSelectionCallback = createCallbackFcn(app, @ImagesTableCellSelection, true);
            app.ImagesLabel = uilabel(app.UIFigure);
            app.ImagesLabel.Text = 'Images:';
            app.AddImageButton = uibutton(app.UIFigure, 'push');
            app.AddImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageButtonPushed, true);
            app.AddImageButton.Text = 'Add';
            app.RemoveImageButton = uibutton(app.UIFigure, 'push');
            app.RemoveImageButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveImageButtonPushed, true);
            app.RemoveImageButton.Text = 'Remove';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiTformGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end